classdef TrainingCNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ProgressBar                 matlab.ui.control.LinearGauge
        GaugeLabel                  matlab.ui.control.Label
        TrainingParamsPanel         matlab.ui.container.Panel
        StartTrainingButton         matlab.ui.control.Button
        PlotsDropDown               matlab.ui.control.DropDown
        PlotsLabel                  matlab.ui.control.Label
        ShuffleDropDown             matlab.ui.control.DropDown
        ShuffleLabel                matlab.ui.control.Label
        ValidationFrequencySpinner  matlab.ui.control.Spinner
        ValidationFrequencyLabel    matlab.ui.control.Label
        GradientThresholdEditField  matlab.ui.control.NumericEditField
        GradientThresholdLabel      matlab.ui.control.Label
        WeightDecayEditField        matlab.ui.control.NumericEditField
        WeightDecayLabel            matlab.ui.control.Label
        MomentumEditField           matlab.ui.control.NumericEditField
        MomentumLabel               matlab.ui.control.Label
        OptimizerDropDown           matlab.ui.control.DropDown
        OptimizerLabel              matlab.ui.control.Label
        EpochsSpinner               matlab.ui.control.Spinner
        EpochsLabel                 matlab.ui.control.Label
        BatchSizeEditField          matlab.ui.control.NumericEditField
        BatchSizeLabel              matlab.ui.control.Label
        LearningRateEditField       matlab.ui.control.NumericEditField
        LearningRateLabel           matlab.ui.control.Label
        TrainingPlot                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DatasetPath
        % Description
        Architecture
        % Description
    end
    
    methods (Access = public)
        
        function results = loadData(app, architecture, dataset)
            app.Architecture = architecture;
            app.DatasetPath = dataset;
        end

        function data = readNPY(filename)
            fid = fopen(filename, 'r');
            magicString = fread(fid, 6, '*char')';
            if ~strcmp(magicString(1:6), '\x93NUMPY')
                error('Not a valid .npy file');
            end
            headerLength = fread(fid, 1, 'uint16');
            header = fread(fid, headerLength, '*char')';
            arrayOffset = ftell(fid);
            fseek(fid, 0, 'eof');
            dataLength = ftell(fid) - arrayOffset;
            fseek(fid, arrayOffset, 'bof');
            data = fread(fid, dataLength, '*uint8');
            fclose(fid);
        end

        function plotTrainingMetrics(trainedNet, app)
            % Example plotting function
            trainingInfo = trainedNet.TrainingHistory;
            plot(app.TrainingPlot, ...
                trainingInfo.Epoch, trainingInfo.TrainingAccuracy, '-o', ...
                trainingInfo.Epoch, trainingInfo.ValidationAccuracy, '-x');
            legend(app.TrainingPlot, 'Training Accuracy', 'Validation Accuracy');
            xlabel(app.TrainingPlot, 'Epoch');
            ylabel(app.TrainingPlot, 'Accuracy');
            title(app.TrainingPlot, 'Training Progress');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
             % Retrieve parameters
            learningRate = app.LearningRateEditField.Value;
            batchSize = app.BatchSizeEditField.Value;
            epochs = app.EpochsSpinner.Value;
            optimizer = app.OptimizerDropDown.Value;
            momentum = app.MomentumEditField.Value;
            weightDecay = app.WeightDecayEditField.Value;
            gradientThreshold = app.GradientThresholdEditField.Value;
            validationFrequency = app.ValidationFrequencySpinner.Value;
            shuffle = app.ShuffleDropDown.Value;
            plots = app.PlotsDropDown.Value;
        
            % Path to Dataset
            datasetPath = app.DatasetPath;
        
            % Unzip and locate files
            unzipFolder = tempname;
            try
                unzip(datasetPath, unzipFolder);
                disp(['Dataset unzipped to: ', unzipFolder]);
        
                % Load .npy files
                x_train = readNPY(fullfile(unzipFolder, 'x_train.npy'));
                y_train = readNPY(fullfile(unzipFolder, 'y_train.npy'));
                x_test = readNPY(fullfile(unzipFolder, 'x_test.npy'));
                y_test = readNPY(fullfile(unzipFolder, 'y_test.npy'));
        
                % Normalize images
                x_train = double(x_train) / 255.0;
                x_test = double(x_test) / 255.0;
        
                % Reshape data if required (assuming MNIST dimensions)
                x_train = reshape(x_train, [size(x_train, 1), 28, 28, 1]); % Adjust if size differs
                x_test = reshape(x_test, [size(x_test, 1), 28, 28, 1]);

                x_train = double(x_train) / 255.0;
                x_train = reshape(x_train, [size(x_train, 1), 28, 28, 1]); % Ensure correct shape
        
                % Convert labels to categorical
                y_train = categorical(y_train);
                y_test = categorical(y_test);
        
                % Define CNN architecture (e.g., LeNet)
                layers = [
                    imageInputLayer([28 28 1])
                    convolution2dLayer(5, 6, 'Padding', 'same')
                    reluLayer
                    maxPooling2dLayer(2, 'Stride', 2)
                    convolution2dLayer(5, 16, 'Padding', 'same')
                    reluLayer
                    maxPooling2dLayer(2, 'Stride', 2)
                    fullyConnectedLayer(120)
                    reluLayer
                    fullyConnectedLayer(84)
                    reluLayer
                    fullyConnectedLayer(numel(categories(y_train)))
                    softmaxLayer
                    classificationLayer];
        
                % Training options
                options = trainingOptions(optimizer, ...
                    'InitialLearnRate', learningRate, ...
                    'MaxEpochs', epochs, ...
                    'MiniBatchSize', batchSize, ...
                    'Momentum', momentum, ...
                    'L2Regularization', weightDecay, ...
                    'GradientThreshold', gradientThreshold, ...
                    'Shuffle', shuffle, ...
                    'ValidationData', {x_test, y_test}, ...
                    'ValidationFrequency', validationFrequency, ...
                    'Plots', plots, ...
                    'Verbose', false);
        
                % Train the network
                app.ProgressBar.Value = 0;
                trainedNet = trainNetwork(x_train, y_train, layers, options);
        
                % Plot results
                plotTrainingMetrics(trainedNet, app);
        
                uialert(app.UIFigure, 'Training complete!', 'Success');
            catch ME
                uialert(app.UIFigure, ['Error: ' ME.message], 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrainingPlot = uiaxes(app.UIFigure);
            title(app.TrainingPlot, 'Training Progress')
            xlabel(app.TrainingPlot, 'Epoch')
            ylabel(app.TrainingPlot, 'Metric')
            zlabel(app.TrainingPlot, 'Z')
            app.TrainingParamsPanel = uipanel(app.UIFigure);
            app.TrainingParamsPanel.Title = 'Training Parameters';
            app.LearningRateLabel = uilabel(app.TrainingParamsPanel);
            app.LearningRateLabel.Text = 'Learning Rate:';
            app.LearningRateEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.LearningRateEditField.Limits = [0.0001 0.01];
            app.LearningRateEditField.Value = 0.001;
            app.BatchSizeLabel = uilabel(app.TrainingParamsPanel);
            app.BatchSizeLabel.Text = 'Batch Size:';
            app.BatchSizeEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.BatchSizeEditField.Limits = [16 256];
            app.BatchSizeEditField.Value = 32;
            app.EpochsLabel = uilabel(app.TrainingParamsPanel);
            app.EpochsLabel.Text = 'Epochs:';
            app.EpochsSpinner = uispinner(app.TrainingParamsPanel);
            app.EpochsSpinner.Limits = [5 100];
            app.EpochsSpinner.Value = 5;
            app.OptimizerLabel = uilabel(app.TrainingParamsPanel);
            app.OptimizerLabel.Text = 'Optimizer:';
            app.OptimizerDropDown = uidropdown(app.TrainingParamsPanel);
            app.OptimizerDropDown.Items = {'sgdm', 'adam', 'rmsprop'};
            app.OptimizerDropDown.Value = 'sgdm';
            app.MomentumLabel = uilabel(app.TrainingParamsPanel);
            app.MomentumLabel.Text = 'Momentum:';
            app.MomentumEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.MomentumEditField.Limits = [0.8 0.99];
            app.MomentumEditField.Value = 0.8;
            app.WeightDecayLabel = uilabel(app.TrainingParamsPanel);
            app.WeightDecayLabel.Text = 'Weight Decay:';
            app.WeightDecayEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.WeightDecayEditField.Limits = [0.0001 0.001];
            app.WeightDecayEditField.Value = 0.0005;
            app.GradientThresholdLabel = uilabel(app.TrainingParamsPanel);
            app.GradientThresholdLabel.Text = 'Gradient Threshold:';
            app.GradientThresholdEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.GradientThresholdEditField.Limits = [1 5];
            app.GradientThresholdEditField.Value = 1;
            app.ValidationFrequencyLabel = uilabel(app.TrainingParamsPanel);
            app.ValidationFrequencyLabel.Text = 'Validation Frequency:';
            app.ValidationFrequencySpinner = uispinner(app.TrainingParamsPanel);
            app.ValidationFrequencySpinner.Limits = [10 1000];
            app.ValidationFrequencySpinner.Value = 10;
            app.ShuffleLabel = uilabel(app.TrainingParamsPanel);
            app.ShuffleLabel.Text = 'Shuffle:';
            app.ShuffleDropDown = uidropdown(app.TrainingParamsPanel);
            app.ShuffleDropDown.Items = {'every-epoch', 'once', 'never'};
            app.ShuffleDropDown.Value = 'every-epoch';
            app.PlotsLabel = uilabel(app.TrainingParamsPanel);
            app.PlotsLabel.Text = 'Plots:';
            app.PlotsDropDown = uidropdown(app.TrainingParamsPanel);
            app.PlotsDropDown.Items = {'training-progress', 'none'};
            app.PlotsDropDown.Value = 'training-progress';
            app.StartTrainingButton = uibutton(app.TrainingParamsPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.ProgressBar = uigauge(app.UIFigure, 'linear');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrainingCNN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end