classdef Filters < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PrimeninekiodfilteraLabel     matlab.ui.control.Label
        SlikasaprimenjenimfilteromLabel  matlab.ui.control.Label
        FilteredImage                 matlab.ui.control.Image
        DFilteringBM3DButton          matlab.ui.control.Button
        BlockMatchingalgoritamButton  matlab.ui.control.Button
        GaussianfilterButton          matlab.ui.control.Button
        MedianfilterButton            matlab.ui.control.Button
        SlikasaumomLabel              matlab.ui.control.Label
        NoisyImage                    matlab.ui.control.Image
        OriginalnaslikaLabel          matlab.ui.control.Label
        PrimeniumButton               matlab.ui.control.Button
        OdaberitejainuumaSlider       matlab.ui.control.Slider
        OdaberitejainuumaSliderLabel  matlab.ui.control.Label
        Original                      matlab.ui.control.Image
    end

   properties (Access = private)
        LoadedImage % Description
    end
    
    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: OdaberitejainuumaSlider
        function OdaberitejainuumaSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Button pushed function: PrimeniumButton
        function PrimeniumButtonPushed(app, event)
            % Učitaj originalnu sliku
            originalImage = app.LoadedImage;
           % Dobij vrednost jačine šuma sa slajdera
            noiseLevel = app.OdaberitejainuumaSlider.Value;  % Vrednost sa slajdera
            noiseLevel = noiseLevel / 10;
            % Dodaj Salt & Pepper šum na sliku
            noisyImage = imnoise(originalImage, 'salt & pepper', noiseLevel);
            % Prikazivanje šumovite slike u aplikaciji
            app.NoisyImage.ImageSource = noisyImage;
        end

        % Button pushed function: MedianfilterButton
        function MedianfilterButtonPushed(app, event)
           noisyImage = app.NoisyImage.ImageSource;
            % Proveri da li je slika u boji (RGB)
            if size(noisyImage, 3) == 3
                % Razdvoj RGB kanale
                redChannel = noisyImage(:, :, 1);
                greenChannel = noisyImage(:, :, 2);
                blueChannel = noisyImage(:, :, 3);
                % Primeni median filter na svaki kanal
                filteredRed = medfilt2(redChannel, [3 3]);
                filteredGreen = medfilt2(greenChannel, [3 3]);
                filteredBlue = medfilt2(blueChannel, [3 3]);
                % Kombinuj filtrirane kanale nazad u RGB sliku
                filteredImage = cat(3, filteredRed, filteredGreen, filteredBlue);
            else
                % Ako je slika u nijansama sive, direktno primeni median filter
                filteredImage = medfilt2(noisyImage, [3 3]);
            end
            % Prikazivanje filtrirane slike u aplikaciji
            app.FilteredImage.ImageSource = filteredImage;
        end

        % Button pushed function: GaussianfilterButton
        function GaussianfilterButtonPushed(app, event)
            % Učitaj šumovitu sliku
            noisyImage = app.NoisyImage.ImageSource;
    
            % Definiši Gaussian kernel
            gaussianKernelSize = 3; % Veličina kernela (npr. 3x3)
            gaussianSigma = 0.5;    % Standardna devijacija za Gaussian filter
            gaussianKernel = fspecial('gaussian', gaussianKernelSize, gaussianSigma);

            % Proveri da li je slika u boji (RGB)
            if size(noisyImage, 3) == 3
                % Razdvoj RGB kanale
                redChannel = noisyImage(:, :, 1);
                greenChannel = noisyImage(:, :, 2);
                blueChannel = noisyImage(:, :, 3);
        
                % Primeni Gaussian filter na svaki kanal
                filteredRed = imfilter(redChannel, gaussianKernel, 'replicate');
                filteredGreen = imfilter(greenChannel, gaussianKernel, 'replicate');
                filteredBlue = imfilter(blueChannel, gaussianKernel, 'replicate');
        
                % Kombinuj filtrirane kanale nazad u RGB sliku
                filteredImage = cat(3, filteredRed, filteredGreen, filteredBlue);
            else
                % Ako je slika u nijansama sive, direktno primeni Gaussian filter
                filteredImage = imfilter(noisyImage, gaussianKernel, 'replicate');
            end
        
            % Prikazivanje filtrirane slike u aplikaciji
            app.FilteredImage.ImageSource = filteredImage;
        end

        % Button pushed function: BlockMatchingalgoritamButton
        function BlockMatchingalgoritamButtonPushed(app, event)
           % Učitaj šumovitu sliku
            noisyImage = app.NoisyImage.ImageSource;
            
            % Parametri Block-Matching algoritma
            blockSize = 8;  % Veličina blokova (npr. 8x8)
            searchWindow = 7;  % Pretraga u okolini (7x7)
            
            % Dimenzije slike
            [height, width, numChannels] = size(noisyImage);
            
            % Kreiraj praznu sliku za filtrirane rezultate
            filteredImage = noisyImage;
            
            % Prolazak kroz sve blokove u slici
            for i = 1:blockSize:height-blockSize+1
                for j = 1:blockSize:width-blockSize+1
                    % Ekstraktuj blok iz šumovite slike
                    block = noisyImage(i:i+blockSize-1, j:j+blockSize-1, :);
                    
                    % Definiši pretragu za najbliži blok u okolini
                    minError = Inf;
                    bestMatch = block;
                    
                    % Pretraga u okolini
                    for m = max(1, i-searchWindow):min(height-blockSize+1, i+searchWindow)
                        for n = max(1, j-searchWindow):min(width-blockSize+1, j+searchWindow)
                            % Ekstraktuj blok iz pretrage
                            candidateBlock = noisyImage(m:m+blockSize-1, n:n+blockSize-1, :);
                            
                            % Izračunaj razliku između blokova (npr. korišćenjem MSE - Mean Squared Error)
                            error = sum((block(:) - candidateBlock(:)).^2);
                            
                            % Ako je trenutni blok bolji, sačuvaj ga
                            if error < minError
                                minError = error;
                                bestMatch = candidateBlock;
                            end
                        end
                    end
                    
                    % Postavi najbolji blok kao filtrirani blok
                    filteredImage(i:i+blockSize-1, j:j+blockSize-1, :) = bestMatch;
                end
            end
            
            % Prikazivanje filtrirane slike u aplikaciji
            app.FilteredImage.ImageSource = filteredImage;
        end

        % Button pushed function: DFilteringBM3DButton
        function DFilteringBM3DButtonPushed(app, event)
            noisyImage = app.NoisyImage.ImageSource;
    
            % Proveri da li je slika u boji (RGB)
            if size(noisyImage, 3) == 3
                % Razdvoj RGB kanale
                redChannel = noisyImage(:, :, 1);
                greenChannel = noisyImage(:, :, 2);
                blueChannel = noisyImage(:, :, 3);
                
                % Primeni BM3D na svaki kanal
                filteredRed = bm3d(redChannel, 'Gaussian', 25); % 25 je standardna vrednost za šum
                filteredGreen = bm3d(greenChannel, 'Gaussian', 25);
                filteredBlue = bm3d(blueChannel, 'Gaussian', 25);
                
                % Kombinuj filtrirane kanale nazad u RGB sliku
                filteredImage = cat(3, filteredRed, filteredGreen, filteredBlue);
            else
                % Ako je slika u nijansama sive, direktno primeni BM3D
                filteredImage = bm3d(noisyImage, 'Gaussian', 25);
            end
            
            % Prikazivanje filtrirane slike u aplikaciji
            app.FilteredImage.ImageSource = filteredImage;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Original = uiimage(app.UIFigure);
            app.OdaberitejainuumaSliderLabel = uilabel(app.UIFigure);
            app.OdaberitejainuumaSliderLabel.Text = 'Odaberite jačinu šuma';
            app.OdaberitejainuumaSlider = uislider(app.UIFigure);
            app.OdaberitejainuumaSlider.Limits = [0 10];
            app.OdaberitejainuumaSlider.ValueChangingFcn = createCallbackFcn(app, @OdaberitejainuumaSliderValueChanging, true);
            app.PrimeniumButton = uibutton(app.UIFigure, 'push');
            app.PrimeniumButton.ButtonPushedFcn = createCallbackFcn(app, @PrimeniumButtonPushed, true);
            app.PrimeniumButton.Text = 'Primeni šum';
            app.OriginalnaslikaLabel = uilabel(app.UIFigure);
            app.OriginalnaslikaLabel.Text = 'Originalna slika';
            app.NoisyImage = uiimage(app.UIFigure);
            app.SlikasaumomLabel = uilabel(app.UIFigure);
            app.SlikasaumomLabel.Text = 'Slika sa šumom';
            app.MedianfilterButton = uibutton(app.UIFigure, 'push');
            app.MedianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianfilterButtonPushed, true);
            app.MedianfilterButton.Text = 'Median filter';
            app.GaussianfilterButton = uibutton(app.UIFigure, 'push');
            app.GaussianfilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianfilterButtonPushed, true);
            app.GaussianfilterButton.Text = 'Gaussian filter';
            app.BlockMatchingalgoritamButton = uibutton(app.UIFigure, 'push');
            app.BlockMatchingalgoritamButton.ButtonPushedFcn = createCallbackFcn(app, @BlockMatchingalgoritamButtonPushed, true);
            app.BlockMatchingalgoritamButton.Text = 'Block-Matching algoritam';
            app.DFilteringBM3DButton = uibutton(app.UIFigure, 'push');
            app.DFilteringBM3DButton.ButtonPushedFcn = createCallbackFcn(app, @DFilteringBM3DButtonPushed, true);
            app.DFilteringBM3DButton.Text = '3D Filtering (BM3D)';
            app.FilteredImage = uiimage(app.UIFigure);
            app.SlikasaprimenjenimfilteromLabel = uilabel(app.UIFigure);
            app.SlikasaprimenjenimfilteromLabel.Text = 'Slika sa primenjenim filterom';
            app.PrimeninekiodfilteraLabel = uilabel(app.UIFigure);
            app.PrimeninekiodfilteraLabel.Text = 'Primeni neki od filtera';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Filters

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end