classdef ImageClassificationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ClassifyButton    matlab.ui.control.Button
        ClassificationResultsTextTextArea  matlab.ui.control.TextArea
        ClassificationResultsTextTextAreaLabel  matlab.ui.control.Label
        LoadImagesButton  matlab.ui.control.Button
        LoadModelButton   matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadModelButton
        function LoadModelButtonPushed(app, event)
            % Otvori dijalog za odabir .mat fajla
    [file, path] = uigetfile('*.mat', 'Select Trained Model');
    if isequal(file,0)
        return; % korisnik je odustao
    end

    % Učitaj sadržaj fajla
    loadedData = load(fullfile(path, file));
    fn = fieldnames(loadedData); % dobij imena promenljivih u fajlu

    if isempty(fn)
        uialert(app.UIFigure, 'Model fajl ne sadrži mrežu!', 'Greška');
        return;
    end

    % Pretpostavimo da je prva promenljiva mreža
    model = loadedData.(fn{1});

    % Provera da li je validna CNN mreža
    if ~isa(model, 'SeriesNetwork') && ~isa(model, 'DAGNetwork')
        uialert(app.UIFigure, ['Prva promenljiva u fajlu nije CNN mreža: ', fn{1}], 'Greška');
        return;
    end

    % Sačuvaj mrežu u UserData za dalje korišćenje
    app.UIFigure.UserData.SelectedModel = model;

    disp(['Model učitan: ', file, ' (variable: ', fn{1}, ')']);
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
selection = questdlg('Testirate jednu sliku ili batch?', ...
    'Select Input', 'Jedna slika', 'Folder', 'Jedna slika');

switch selection
    case 'Jedna slika'
        [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select Image');
        if isequal(file,0)
            return;
        end
        fullFile = fullfile(path, file);
        try
            img = imread(fullFile);
        catch
            uialert(app.UIFigure, 'Odabrani fajl nije podržana slika!', 'Greška');
            return;
        end
        app.UIFigure.UserData.TestImageFiles = {fullFile};
        imshow(img, 'Parent', app.UIAxes);

    case 'Folder'
        folder = uigetdir('', 'Select Image Folder');
        if isequal(folder,0)
            return;
        end
        % Pronađi sve fajlove sa podržanim ekstenzijama
        files = dir(fullfile(folder, '*.jpg'));
        files = [files; dir(fullfile(folder, '*.png'))];
        files = [files; dir(fullfile(folder, '*.bmp'))];

        validFiles = {};
        for k = 1:numel(files)
            fullFile = fullfile(files(k).folder, files(k).name);
            try
                imread(fullFile); % test da li je fajl validna slika
                validFiles{end+1} = fullFile; %#ok<AGROW>
            catch
                fprintf('Preskočen fajl: %s\n', fullFile);
            end
        end

        if isempty(validFiles)
            uialert(app.UIFigure, 'Nema validnih slika u folderu!', 'Greška');
            return;
        end

        app.UIFigure.UserData.TestImageFiles = validFiles;
        disp([num2str(numel(validFiles)), ' valid images loaded for batch']);
end
        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
        % Provera da li je model i slike učitan
    if isempty(app.UIFigure.UserData) || ...
       ~isfield(app.UIFigure.UserData, 'SelectedModel') || ...
       ~isfield(app.UIFigure.UserData, 'TestImageFiles') || ...
       isempty(app.UIFigure.UserData.SelectedModel) || ...
       isempty(app.UIFigure.UserData.TestImageFiles)
   
        uialert(app.UIFigure, 'Prvo učitaj model i slike!', 'Greška');
        return;
    end

    model = app.UIFigure.UserData.SelectedModel;
    imageFiles = app.UIFigure.UserData.TestImageFiles;
    resultsStr = strings(0,1);

    % Dobij ulazne dimenzije modela
    inputSize = model.Layers(1).InputSize;

    for i = 1:numel(imageFiles)
        img = imread(imageFiles{i});

        % Resize slike prema dimenzijama modela
        img = imresize(img, inputSize(1:2));

        % Pretvori u RGB ako je model treniran za 3 kanala
        if inputSize(3) == 3 && size(img,3) == 1
            img = repmat(img, [1 1 3]);
        elseif inputSize(3) == 1 && size(img,3) == 3
            img = rgb2gray(img);
        end

        [label, scores] = classify(model, img);

        % top-1 i top-5
        [sortedScores, idx] = sort(scores, 'descend');
        classes = model.Layers(end).Classes;
        top5 = classes(idx(1:min(5,end)));

        resText = sprintf('Slika: %s\nTop-1: %s (%.2f%%)\nTop-5: ', ...
            imageFiles{i}, string(label), 100*sortedScores(1));
        for k = 1:numel(top5)
            resText = [resText, sprintf('%s (%.2f%%) ', string(top5(k)), 100*sortedScores(k))];
        end
        resultsStr(end+1) = resText;

        % update UIAxes samo za prvu sliku
        if i == 1
            imshow(img, 'Parent', app.UIAxes);
        end
    end

    app.ClassificationResultsTextTextArea.Value = resultsStr;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'SampleImageAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadModelButton = uibutton(app.UIFigure, 'push');
            app.LoadModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadModelButtonPushed, true);
            app.LoadModelButton.Text = 'LoadModelButton';
            app.LoadImagesButton = uibutton(app.UIFigure, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'LoadImagesButton';
            app.ClassificationResultsTextTextAreaLabel = uilabel(app.UIFigure);
            app.ClassificationResultsTextTextAreaLabel.Text = 'ClassificationResultsText';
            app.ClassificationResultsTextTextArea = uitextarea(app.UIFigure);
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'ClassifyButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageClassificationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end