classdef TrainingCNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TrainingParamsPanel         matlab.ui.container.Panel
        AccuracyLabel               matlab.ui.control.Label
        TestingAccuracyLabel        matlab.ui.control.Label
        StartTrainingButton         matlab.ui.control.Button
        PlotsDropDown               matlab.ui.control.DropDown
        PlotsLabel                  matlab.ui.control.Label
        ShuffleDropDown             matlab.ui.control.DropDown
        ShuffleLabel                matlab.ui.control.Label
        ValidationFrequencySpinner  matlab.ui.control.Spinner
        ValidationFrequencyLabel    matlab.ui.control.Label
        GradientThresholdEditField  matlab.ui.control.NumericEditField
        GradientThresholdLabel      matlab.ui.control.Label
        WeightDecayEditField        matlab.ui.control.NumericEditField
        WeightDecayLabel            matlab.ui.control.Label
        MomentumEditField           matlab.ui.control.NumericEditField
        MomentumLabel               matlab.ui.control.Label
        OptimizerDropDown           matlab.ui.control.DropDown
        OptimizerLabel              matlab.ui.control.Label
        EpochsSpinner               matlab.ui.control.Spinner
        EpochsLabel                 matlab.ui.control.Label
        BatchSizeEditField          matlab.ui.control.NumericEditField
        BatchSizeLabel              matlab.ui.control.Label
        LearningRateEditField       matlab.ui.control.NumericEditField
        LearningRateLabel           matlab.ui.control.Label
    end

    properties (Access = private)
        DatasetPath
        Architecture
        InputDimensions
    end

    methods (Access = public)
        function results = loadData(app, architecture, dataset, dimensions)
            disp(dataset);
            app.Architecture = architecture;
            app.DatasetPath = dataset;
            app.InputDimensions = dimensions;
        end
    end

    methods (Access = private)
        function layers = getLayers(app, archName, inputSize, numClasses)
            switch archName
                case "LeNet"
                    layers = [
                        imageInputLayer(inputSize)
    
                        convolution2dLayer(5, 6, 'Padding', 'same')
                        batchNormalizationLayer
                        reluLayer
                        averagePooling2dLayer(2, 'Stride', 2)
    
                        convolution2dLayer(5, 16, 'Padding', 'same')
                        batchNormalizationLayer
                        reluLayer
                        averagePooling2dLayer(2, 'Stride', 2)
    
                        fullyConnectedLayer(120)
                        reluLayer
                        fullyConnectedLayer(84)
                        reluLayer
                        fullyConnectedLayer(numClasses)
                        softmaxLayer
                        classificationLayer];
    
                case "AlexNet"
                    % You can use pretrained AlexNet (requires Deep Learning Toolbox Model for AlexNet)
                    inputSize = [28 28 1];   % Fashion-MNIST size
                    numClasses = 10;
                
                    layers = [
                        imageInputLayer(inputSize,"Name","input")
                
                        convolution2dLayer(3,32,"Stride",1,"Padding","same","Name","conv1")
                        reluLayer("Name","relu1")
                        maxPooling2dLayer(2,"Stride",2,"Name","pool1")
                
                        convolution2dLayer(3,64,"Padding","same","Name","conv2")
                        reluLayer("Name","relu2")
                        maxPooling2dLayer(2,"Stride",2,"Name","pool2")
                
                        convolution2dLayer(3,128,"Padding","same","Name","conv3")
                        reluLayer("Name","relu3")
                
                        fullyConnectedLayer(256,"Name","fc1")
                        reluLayer("Name","relu4")
                        dropoutLayer(0.5,"Name","drop1")
                
                        fullyConnectedLayer(numClasses,"Name","fc2")
                        softmaxLayer("Name","softmax")
                        classificationLayer("Name","classOutput")
                    ];
                    % Replace final layer for your dataset
                    layers(end-2) = fullyConnectedLayer(numClasses);
                    layers(end)   = classificationLayer;
    
                case "VGG-16"
                    net = vgg16; % pretrained
                    layers = net.Layers;
                    layers(end-2) = fullyConnectedLayer(numClasses);
                    layers(end)   = classificationLayer;
    
                case "ResNet"
                    net = resnet50;
                    lgraph = layerGraph(net);
                    % Replace final layers
                    newFc = fullyConnectedLayer(numClasses, 'Name','new_fc');
                    newClass = classificationLayer('Name','new_classoutput');
                    lgraph = replaceLayer(lgraph,'fc1000',newFc);
                    lgraph = replaceLayer(lgraph,'ClassificationLayer_fc1000',newClass);
                    layers = lgraph;
    
                otherwise
                    % Fallback: simple small CNN
                    layers = [
                        imageInputLayer(inputSize)
                        convolution2dLayer(3, 8, 'Padding', 'same')
                        batchNormalizationLayer
                        reluLayer
                        maxPooling2dLayer(2, 'Stride', 2)
                        convolution2dLayer(3, 16, 'Padding', 'same')
                        batchNormalizationLayer
                        reluLayer
                        fullyConnectedLayer(numClasses)
                        softmaxLayer
                        classificationLayer];
            end
        end

        function options = getTrainingOptions(app, optimizer, lr, epochs, batchSize, momentum, weightDecay, shuffle, plot)
            switch optimizer
                case 'sgdm'
                    options = trainingOptions('sgdm', ...
                        'InitialLearnRate', lr, ...
                        'MaxEpochs', epochs, ...
                        'MiniBatchSize', batchSize, ...
                        'Momentum', momentum, ...
                        'L2Regularization', weightDecay, ...
                        'Shuffle', shuffle, ...
                        'Verbose', false, ...
                        'Plots', plot, ...
                        'ExecutionEnvironment','auto');
        
                case 'adam'
                    options = trainingOptions('adam', ...
                        'InitialLearnRate', lr, ...
                        'MaxEpochs', epochs, ...
                        'MiniBatchSize', batchSize, ...
                        'L2Regularization', weightDecay, ...
                        'Shuffle', shuffle, ...
                        'Verbose', false, ...
                        'Plots', plot, ...
                        'ExecutionEnvironment','auto');
        
                case 'rmsprop'
                    options = trainingOptions('rmsprop', ...
                        'InitialLearnRate', lr, ...
                        'MaxEpochs', epochs, ...
                        'MiniBatchSize', batchSize, ...
                        'L2Regularization', weightDecay, ...
                        'Shuffle', shuffle, ...
                        'Verbose', false, ...
                        'Plots', plot, ...
                        'ExecutionEnvironment','auto');
        
                otherwise
                    error('Unsupported optimizer: %s', optimizer);
            end
        end


         function trainAndSaveCNN(app, lr, batchSize, epochs, momentum, weightDecay, optimizer, shuffle, plot, savePath)
             disp(app.DatasetPath);
             subDirs = dir(app.DatasetPath);
                subDirs = subDirs([subDirs.isdir] & ~startsWith({subDirs.name}, '.'));

                hasTrain = any(strcmpi({subDirs.name}, 'training'));
                hasTest  = any(strcmpi({subDirs.name}, 'testing'));
                
                if ~(hasTrain && hasTest) && numel(subDirs) == 1
                    app.DatasetPath = fullfile(app.DatasetPath, subDirs(1).name);
                end

            imdsTrain = imageDatastore(fullfile(app.DatasetPath, 'training'), ...
                'IncludeSubfolders', true, ...
                'LabelSource', 'foldernames');
            
            imdsTest = imageDatastore(fullfile(app.DatasetPath, 'testing'), ...
                'IncludeSubfolders', true, ...
                'LabelSource', 'foldernames');
       
            numClasses = numel(categories(imdsTrain.Labels));
            
            % Get layers depending on chosen architecture
            layers = getLayers(app, app.Architecture, app.InputDimensions, numClasses);
            
            % Training options
            options = app.getTrainingOptions(optimizer, lr, epochs, batchSize, momentum, weightDecay, shuffle, plot);
            
            net = trainNetwork(imdsTrain, layers, options);
        
            % Evaluate accuracy (optional, for display)
            preds = classify(net, imdsTest);
            accuracy = sum(preds == imdsTest.Labels) / numel(imdsTest.Labels);
            fprintf("Test Accuracy: %.2f%%\n", accuracy * 100);
            app.AccuracyLabel.Text = sprintf('%.2f%%', accuracy * 100);
        
            % Save trained model
            save(savePath, 'net', 'accuracy');  % Save both network and accuracy
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartTrainingButton
        function StartTrainingButtonPushed(app, event)
            lr = app.LearningRateEditField.Value;
            batchSize = app.BatchSizeEditField.Value;
            epochs = app.EpochsSpinner.Value;
            momentum = app.MomentumEditField.Value;
            weightDecay = app.WeightDecayEditField.Value;
            optimizer = app.OptimizerDropDown.Value;
            shuffle = app.ShuffleDropDown.Value;
            plots = app.PlotsDropDown.Value;
        
            % Choose where to save
            [file, path] = uiputfile('trainedCNN.mat', 'Save Trained Model As');
            if isequal(file, 0)
                uialert(app.UIFigure, 'Training canceled. No file selected.', 'Canceled');
                return;
            end
            savePath = fullfile(path, file);
        
            % Call training function
            app.trainAndSaveCNN(lr, batchSize, epochs, momentum, weightDecay, optimizer, shuffle, plots, savePath);
        
            uialert(app.UIFigure, 'Training complete and model saved.', 'Success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TrainingParamsPanel = uipanel(app.UIFigure);
            app.TrainingParamsPanel.Title = 'Training Parameters';
            app.LearningRateLabel = uilabel(app.TrainingParamsPanel);
            app.LearningRateLabel.Text = 'Learning Rate:';
            app.LearningRateEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.LearningRateEditField.Limits = [0.0001 0.01];
            app.LearningRateEditField.Value = 0.001;
            app.BatchSizeLabel = uilabel(app.TrainingParamsPanel);
            app.BatchSizeLabel.Text = 'Batch Size:';
            app.BatchSizeEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.BatchSizeEditField.Limits = [16 256];
            app.BatchSizeEditField.Value = 32;
            app.EpochsLabel = uilabel(app.TrainingParamsPanel);
            app.EpochsLabel.Text = 'Epochs:';
            app.EpochsSpinner = uispinner(app.TrainingParamsPanel);
            app.EpochsSpinner.Limits = [5 100];
            app.EpochsSpinner.Value = 5;
            app.OptimizerLabel = uilabel(app.TrainingParamsPanel);
            app.OptimizerLabel.Text = 'Optimizer:';
            app.OptimizerDropDown = uidropdown(app.TrainingParamsPanel);
            app.OptimizerDropDown.Items = {'sgdm', 'adam', 'rmsprop'};
            app.OptimizerDropDown.Value = 'sgdm';
            app.MomentumLabel = uilabel(app.TrainingParamsPanel);
            app.MomentumLabel.Text = 'Momentum:';
            app.MomentumEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.MomentumEditField.Limits = [0.8 0.99];
            app.MomentumEditField.Value = 0.8;
            app.WeightDecayLabel = uilabel(app.TrainingParamsPanel);
            app.WeightDecayLabel.Text = 'Weight Decay:';
            app.WeightDecayEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.WeightDecayEditField.Limits = [0.0001 0.001];
            app.WeightDecayEditField.Value = 0.0005;
            app.GradientThresholdLabel = uilabel(app.TrainingParamsPanel);
            app.GradientThresholdLabel.Text = 'Gradient Threshold:';
            app.GradientThresholdEditField = uieditfield(app.TrainingParamsPanel, 'numeric');
            app.GradientThresholdEditField.Limits = [1 5];
            app.GradientThresholdEditField.Value = 1;
            app.ValidationFrequencyLabel = uilabel(app.TrainingParamsPanel);
            app.ValidationFrequencyLabel.Text = 'Validation Frequency:';
            app.ValidationFrequencySpinner = uispinner(app.TrainingParamsPanel);
            app.ValidationFrequencySpinner.Limits = [10 1000];
            app.ValidationFrequencySpinner.Value = 10;
            app.ShuffleLabel = uilabel(app.TrainingParamsPanel);
            app.ShuffleLabel.Text = 'Shuffle:';
            app.ShuffleDropDown = uidropdown(app.TrainingParamsPanel);
            app.ShuffleDropDown.Items = {'every-epoch', 'once', 'never'};
            app.ShuffleDropDown.Value = 'every-epoch';
            app.PlotsLabel = uilabel(app.TrainingParamsPanel);
            app.PlotsLabel.Text = 'Plots:';
            app.PlotsDropDown = uidropdown(app.TrainingParamsPanel);
            app.PlotsDropDown.Items = {'training-progress', 'none'};
            app.PlotsDropDown.Value = 'training-progress';
            app.StartTrainingButton = uibutton(app.TrainingParamsPanel, 'push');
            app.StartTrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrainingButtonPushed, true);
            app.StartTrainingButton.Text = 'Start Training';
            app.TestingAccuracyLabel = uilabel(app.TrainingParamsPanel);
            app.TestingAccuracyLabel.Text = 'Testing Accuracy:';
            app.AccuracyLabel = uilabel(app.TrainingParamsPanel);
            app.AccuracyLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrainingCNN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end