classdef WienerFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Original             matlab.ui.control.Image
        PSFEditField         matlab.ui.control.EditField
        PSFEditFieldLabel    matlab.ui.control.Label
        SNREditField         matlab.ui.control.NumericEditField
        SNREditField_2Label  matlab.ui.control.Label
        ApplyFilterButton    matlab.ui.control.Button
        FilteredAxes         matlab.ui.control.UIAxes
    end

   
    properties (Access = private)
        LoadedImage % Description
    end
    
    methods (Access = public)
        
        function loadImage(app, image)
        % Display the passed image in a UIAxes
            app.LoadedImage = image;
            app.Original.ImageSource = image;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
  % Proveri da li postoji učitana slika
    %if isempty(app.OriginalAxes.UserData)
       % uialert(app.UIFigure, 'Molimo učitajte sliku pre primene filtera.', 'Greška');
       % return;
    %end

    % Dohvati učitanu sliku
    originalImage = app.LoadedImage;

    % Proveri da li je slika grayscale (ako nije, konvertuj je)
    if size(originalImage, 3) == 3
        grayImage = rgb2gray(originalImage);
    else
        grayImage = originalImage;
    end

    % Parsiraj PSF vrednost iz unosa kao tekst
    psfText = app.PSFEditField.Value;  % Tekstualni unos PSF-a
    psfMatrix = str2num(psfText);  % Parsiranje teksta u matricu

    % Proveri da li je PSF ispravno unet
    if isempty(psfMatrix) || ~ismatrix(psfMatrix) || size(psfMatrix, 1) ~= 3 || size(psfMatrix, 2) ~= 3
        uialert(app.UIFigure, 'PSF format nije ispravan. Unesite matricu u formatu: [0 1 0; 1 1 1; 0 1 0]', 'Greška');
        return;
    end

    % Prikazivanje PSF matrice u komandnom prozoru radi provere
    disp('PSF Matriza:');
    disp(psfMatrix);

    % Proveri da li je SNR unet
    snrValue = app.SNREditField.Value;  % Unos za SNR
    if isempty(snrValue) || snrValue <= 0
        uialert(app.UIFigure, 'Molimo unesite validan SNR.', 'Greška');
        return;
    end

    % Primeni dekonvoluciju sa Wiener filterom koristeći PSF i SNR
    deconvolvedImage = deconvwnr(grayImage, psfMatrix, snrValue);

    % Normalizuj dekonvoluciju na opseg [0, 255]
    deconvolvedImage = mat2gray(deconvolvedImage);  % Normalizuje sliku na [0, 1]
    deconvolvedImage = uint8(deconvolvedImage * 255);  % Skalira vrednosti na [0, 255]

    % Proveri da li je slika pravilno generisana
    if all(deconvolvedImage(:) == 0)
        uialert(app.UIFigure, 'Dekonvolucija nije dala validan rezultat. Proverite vrednosti PSF i SNR.', 'Greška');
        return;
    end

    % Prikaz filtrirane slike na FilteredAxes
    imshow(deconvolvedImage, 'Parent', app.FilteredAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FilteredAxes = uiaxes(app.UIFigure);
            title(app.FilteredAxes, 'Filtered Image')
            zlabel(app.FilteredAxes, 'Z')
            app.ApplyFilterButton = uibutton(app.UIFigure, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.SNREditField_2Label = uilabel(app.UIFigure);
            app.SNREditField_2Label.Text = 'SNR';
            app.SNREditField = uieditfield(app.UIFigure, 'numeric');
            app.PSFEditFieldLabel = uilabel(app.UIFigure);
            app.PSFEditFieldLabel.Text = 'PSF';
            app.PSFEditField = uieditfield(app.UIFigure, 'text');
            app.PSFEditField.Value = '0 1 0; 1 1 1; 0 1 0';
            app.Original = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WienerFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end